classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        NoiseCheckBox                  matlab.ui.control.CheckBox
        BlurEffectDropDown             matlab.ui.control.DropDown
        BlurEffectDropDownLabel        matlab.ui.control.Label
        thetaSpinner                   matlab.ui.control.Spinner
        thetaSpinnerLabel              matlab.ui.control.Label
        lenSpinner                     matlab.ui.control.Spinner
        lenSpinnerLabel                matlab.ui.control.Label
        DeconvolutionOptionLabel       matlab.ui.control.Label
        deconvCheckbox                 matlab.ui.control.CheckBox
        BrightnessfactorSpinner        matlab.ui.control.Spinner
        BrightnessfactorSpinnerLabel   matlab.ui.control.Label
        nforbutterwothSpinner          matlab.ui.control.Spinner
        nforbutterwothSpinnerLabel     matlab.ui.control.Label
        D0Spinner                      matlab.ui.control.Spinner
        D0SpinnerLabel                 matlab.ui.control.Label
        ConvolutioalMaskTextArea       matlab.ui.control.TextArea
        ConvolutioalMaskTextAreaLabel  matlab.ui.control.Label
        Matrix                         matlab.ui.control.Label
        FileName                       matlab.ui.control.Label
        OKButton                       matlab.ui.control.Button
        MatrixLabel                    matlab.ui.control.Label
        FilterButtonGroup              matlab.ui.container.ButtonGroup
        ButterworthButton              matlab.ui.control.RadioButton
        GaussianButton                 matlab.ui.control.RadioButton
        IdealButton                    matlab.ui.control.RadioButton
        DropDown                       matlab.ui.control.DropDown
        ImageSelectedLabel             matlab.ui.control.Label
        SelectImageButton              matlab.ui.control.Button
        OutputImagePanel               matlab.ui.container.Panel
        OutputLibrary                  matlab.ui.control.UIAxes
        OutputImage                    matlab.ui.control.UIAxes
        InputImagePanel                matlab.ui.container.Panel
        InputImage                     matlab.ui.control.UIAxes
        PenapisanCitraLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        ImageInput % Description
        ImageOutput
        ConvolutionMatrix
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
            % Pilih file gambar menggunakan uigetfile
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'});
    
            % Jika pengguna memilih file
            if isequal(file, 0)
                disp('User cancelled the operation');
            else
                % Baca gambar
                img = imread(fullfile(path, file));
                app.FileName.Text = file;
        
                % Tampilkan gambar di UIAxes
                imshow(img, 'Parent', app.InputImage);
                app.ImageInput = img;
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            value = app.DropDown.Value;

            mat = app.ConvolutioalMaskTextArea.Value;
            matrix = strjoin(mat, ' ');
            app.Matrix.Text = matrix;
            switch value
                case 'Convolution'
                    outputImg = convolution(double(app.ImageInput),double(str2num(matrix)));
                    imshow(outputImg, 'Parent', app.OutputImage);

                    outputLibrary = convolutionLibrary(app.ImageInput,str2num(matrix));
                    imshow(outputLibrary,'Parent',app.OutputLibrary);
                case 'High-pass Filtering'
                    filter = app.FilterButtonGroup.SelectedObject.Text;

                    outputImg = highPassFilter(app.ImageInput,filter,app.D0Spinner.Value,app.nforbutterwothSpinner.Value);
                    imshow(outputImg, 'Parent', app.OutputImage);
                case 'Low-pass Filtering'
                    filter = app.FilterButtonGroup.SelectedObject.Text;

                    outputImg = lowPassFilter(app.ImageInput,filter,app.D0Spinner.Value,app.nforbutterwothSpinner.Value);
                    imshow(outputImg, 'Parent', app.OutputImage);
                case 'Image Brightening'
                    outputImg = brightening(app.ImageInput,app.BrightnessfactorSpinner.Value);

                    imshow(outputImg, 'Parent', app.OutputImage);
                case 'Deconvolution'
                    outputImg = deconvolution(im2double(app.ImageInput), app.deconvCheckbox.Value, app.NoiseCheckBox.Value, app.BlurEffectDropDown.Value, app.lenSpinner.Value, app.thetaSpinner.Value);

                    imshow(outputImg, 'Parent', app.OutputImage);
            end
        end

        % Selection changed function: FilterButtonGroup
        function FilterButtonGroupSelectionChanged(app, event)
            selectedButton = app.FilterButtonGroup.SelectedObject;
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            cla(app.OutputImage);
            cla(app.OutputLibrary);
            cla(app.InputImage);
        end

        % Value changed function: BlurEffectDropDown
        function BlurEffectDropDownValueChanged(app, event)
            value = app.BlurEffectDropDown.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PenapisanCitraLabel = uilabel(app.UIFigure);
            app.PenapisanCitraLabel.Text = 'Penapisan Citra';
            app.InputImagePanel = uipanel(app.UIFigure);
            app.InputImagePanel.Title = 'Input Image';
            app.InputImage = uiaxes(app.InputImagePanel);
            title(app.InputImage, 'Input Image')
            zlabel(app.InputImage, 'Z')
            app.InputImage.XTick = [0 0.5 1];
            app.InputImage.XTickLabel = '';
            app.InputImage.YTickLabel = '';
            app.OutputImagePanel = uipanel(app.UIFigure);
            app.OutputImagePanel.Title = 'Output Image';
            app.OutputImage = uiaxes(app.OutputImagePanel);
            title(app.OutputImage, 'Output Image')
            zlabel(app.OutputImage, 'Z')
            app.OutputImage.XTick = [0 0.5 1];
            app.OutputImage.XTickLabel = '';
            app.OutputImage.YTickLabel = '';
            app.OutputLibrary = uiaxes(app.OutputImagePanel);
            title(app.OutputLibrary, 'Output with library')
            zlabel(app.OutputLibrary, 'Z')
            app.OutputLibrary.XTick = [0 0.5 1];
            app.OutputLibrary.XTickLabel = '';
            app.OutputLibrary.YTickLabel = '';
            app.SelectImageButton = uibutton(app.UIFigure, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.ImageSelectedLabel = uilabel(app.UIFigure);
            app.ImageSelectedLabel.Text = 'Image Selected:';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Convolution', 'Low-pass Filtering', 'High-pass Filtering', 'Image Brightening', 'Noise Reduction (Salt and Pepper)', 'Noise Reduction (Periodic)', 'Deconvolution'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Convolution';
            app.FilterButtonGroup = uibuttongroup(app.UIFigure);
            app.FilterButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FilterButtonGroupSelectionChanged, true);
            app.FilterButtonGroup.Title = 'Filter';
            app.IdealButton = uiradiobutton(app.FilterButtonGroup);
            app.IdealButton.Text = 'Ideal';
            app.IdealButton.Value = true;
            app.GaussianButton = uiradiobutton(app.FilterButtonGroup);
            app.GaussianButton.Text = 'Gaussian';
            app.ButterworthButton = uiradiobutton(app.FilterButtonGroup);
            app.ButterworthButton.Text = 'Butterworth';
            app.MatrixLabel = uilabel(app.UIFigure);
            app.MatrixLabel.Text = 'Matrix:';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.FileName = uilabel(app.UIFigure);
            app.FileName.Text = '---';
            app.Matrix = uilabel(app.UIFigure);
            app.Matrix.Text = '-';
            app.ConvolutioalMaskTextAreaLabel = uilabel(app.UIFigure);
            app.ConvolutioalMaskTextAreaLabel.Text = 'Convolutioal Mask';
            app.ConvolutioalMaskTextArea = uitextarea(app.UIFigure);
            app.D0SpinnerLabel = uilabel(app.UIFigure);
            app.D0SpinnerLabel.Text = 'D0';
            app.D0Spinner = uispinner(app.UIFigure);
            app.D0Spinner.Value = 50;
            app.nforbutterwothSpinnerLabel = uilabel(app.UIFigure);
            app.nforbutterwothSpinnerLabel.Text = 'n (for butterwoth)';
            app.nforbutterwothSpinner = uispinner(app.UIFigure);
            app.nforbutterwothSpinner.Value = 1;
            app.BrightnessfactorSpinnerLabel = uilabel(app.UIFigure);
            app.BrightnessfactorSpinnerLabel.Text = 'Brightness factor';
            app.BrightnessfactorSpinner = uispinner(app.UIFigure);
            app.deconvCheckbox = uicheckbox(app.UIFigure);
            app.deconvCheckbox.Text = 'Include motion bluring on image';
            app.DeconvolutionOptionLabel = uilabel(app.UIFigure);
            app.DeconvolutionOptionLabel.Text = 'Deconvolution Option';
            app.lenSpinnerLabel = uilabel(app.UIFigure);
            app.lenSpinnerLabel.Text = 'len';
            app.lenSpinner = uispinner(app.UIFigure);
            app.thetaSpinnerLabel = uilabel(app.UIFigure);
            app.thetaSpinnerLabel.Text = 'theta';
            app.thetaSpinner = uispinner(app.UIFigure);
            app.BlurEffectDropDownLabel = uilabel(app.UIFigure);
            app.BlurEffectDropDownLabel.Text = 'Blur Effect';
            app.BlurEffectDropDown = uidropdown(app.UIFigure);
            app.BlurEffectDropDown.Items = {'Motion', 'Gaussian'};
            app.BlurEffectDropDown.ValueChangedFcn = createCallbackFcn(app, @BlurEffectDropDownValueChanged, true);
            app.BlurEffectDropDown.Value = 'Motion';
            app.NoiseCheckBox = uicheckbox(app.UIFigure);
            app.NoiseCheckBox.Text = 'Noise';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end